classdef RFSoC_MVDR_Demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RFSoCMVDRDemoUIFigure   matlab.ui.Figure
        DiagonalLoadingEdit     matlab.ui.control.NumericEditField
        MatrixsolveregularizationEditFieldLabel  matlab.ui.control.Label
        SteerLock               matlab.ui.control.CheckBox
        SignalofInterestPanel   matlab.ui.container.Panel
        SignalGainLabel         matlab.ui.control.Label
        SignalAngleLabel        matlab.ui.control.Label
        SignalAngleSpinner      matlab.ui.control.Spinner
        degreesSpinnerLabel     matlab.ui.control.Label
        SignalGainSpinner       matlab.ui.control.Spinner
        dBLabel                 matlab.ui.control.Label
        SignalAngleSlider       matlab.ui.control.Slider
        InterferingSignalPanel  matlab.ui.container.Panel
        InterfererGainLabel     matlab.ui.control.Label
        InterfererAngleLabel    matlab.ui.control.Label
        InterfererAngleSpinner  matlab.ui.control.Spinner
        degreesSpinnerLabel_2   matlab.ui.control.Label
        InterfererGainSpinner   matlab.ui.control.Spinner
        dBLabel_2               matlab.ui.control.Label
        InterfererAngleSlider   matlab.ui.control.Slider
        SteeringAngleSlider     matlab.ui.control.Slider
        degreesSpinnerLabel_3   matlab.ui.control.Label
        SteeringAngleSpinner    matlab.ui.control.Spinner
        SteeringAngleLabel      matlab.ui.control.Label
        ConstellationPlot       matlab.ui.control.UIAxes
        SpectrumPlot            matlab.ui.control.UIAxes
        ResponsePlot            matlab.ui.control.UIAxes
    end

%   Copyright 2021-2023 The MathWorks, Inc.
    properties (Access = private)

        % RFSoCMVDRDemo object
        hDemo

        % Phase shift beamformer response for current steering angle
        phaseShiftResponse

        % Display update timer
        displayUpdateTimer

        % Line handles
        hSignalAngleLine
        hInterfererAngleLine
        hMVDRResponseLine
        hPhaseShiftResponseLine
        hDesiredSteeringAngleLine

        % Plot handles
        hSpectrum
        hConstellation

        % Plot settings
        FreqAxis
        spectrumPoints = 2048;

        % Flag to force update MVDR response
        doMVDRUpdate = false;

        % Counter to limit MVDR update rate
        MVDRUpdateCount = 1;

        % Flag to initialize axes on spectrum/constellation plots
        doDisplayInit = false;
    end

    methods (Access = private)

        function initPlot(app)
            hold(app.ResponsePlot,'on');
            app.hMVDRResponseLine = line(app.ResponsePlot,-90:90,zeros(181,1));
            app.hPhaseShiftResponseLine = line(app.ResponsePlot,-90:90,zeros(181,1),'LineWidth',2);
            app.hSignalAngleLine = yline(app.ResponsePlot,0);
            app.hInterfererAngleLine = yline(app.ResponsePlot,0);
            app.hDesiredSteeringAngleLine = yline(app.ResponsePlot,0);
            legend(app.ResponsePlot,{'MVDR','PhaseShift'},'Location','SouthEast');

            disableDefaultInteractivity(app.ResponsePlot);
            disableDefaultInteractivity(app.ConstellationPlot);
            disableDefaultInteractivity(app.SpectrumPlot);

            app.hSpectrum = line(app.SpectrumPlot,1:app.spectrumPoints,zeros(app.spectrumPoints,1));
            app.hConstellation = scatter(app.ConstellationPlot,0,0,'filled');
            set(app.hConstellation.Parent,'XLim',[-1.2 1.2]);
            set(app.hConstellation.Parent,'YLim',[-1.2 1.2]);

            app.doDisplayInit = true;
        end

        function updateDisplays(app)
            % MVDR beam response
            % Update this once every 10 frames, or on-demand after a slider
            % is changed
            if (app.MVDRUpdateCount == 1) || (app.doMVDRUpdate)
                resp = app.hDemo.getArrayResponse('mvdr');
                set(app.hMVDRResponseLine ,'YData',resp,'XData',-90:90,'Color','#0072BD','LineStyle','-','LineWidth',2);
                app.doMVDRUpdate = false;
            end
            app.MVDRUpdateCount = mod(app.MVDRUpdateCount,10)+1;

            % Get data to plot
            [beamData,qpskData,fs] = app.hDemo.getUIData();

            % Compute values for spectrum plot
            N = app.spectrumPoints;
            Y = fftshift(fft(beamData(1:N)));
            YdB = 20*log(abs(Y));
            if app.doDisplayInit
                faxis = (-N/2:N/2-1)*(fs/N); % zero-centered frequency range
                faxis(end) = fs/2;
                app.FreqAxis = faxis;
            end

            % Update spectrum plot
            set(app.hSpectrum,'XData',app.FreqAxis,'YData',YdB);

            % Update constellation plot
            set(app.hConstellation,'XData',real(qpskData),'YData',imag(qpskData));

            % Set plot labels/colors on init
            if app.doDisplayInit
                fidxjump = N/8;
                FreqTick = [app.FreqAxis(1:fidxjump:end) app.FreqAxis(end)];
                app.hSpectrum.Parent.XLim = [app.FreqAxis(1) app.FreqAxis(end)];
                app.hSpectrum.Parent.XTickLabel = strsplit(num2str(FreqTick/1e6,5));
                app.hSpectrum.Parent.XTick = FreqTick;
                YLim = [-100 100];
                YTick = YLim(1):25:YLim(2);
                app.hSpectrum.Parent.YTickLabel = strsplit(num2str( YTick ));
                app.hSpectrum.Parent.YTick = YTick;
                app.hSpectrum.Parent.YLim = YLim;
                set(app.hSpectrum,'Color',[1 1 0]);

                set(app.hConstellation,'CData',[1 1 0],'SizeData',5);
                hold(app.hConstellation.Parent,'on'); % #ok
                quad_xcoord = [1/sqrt(2), 1/sqrt(2), -1/sqrt(2),-1/sqrt(2)];
                quad_ycoord = [1/sqrt(2), -1/sqrt(2), 1/sqrt(2),-1/sqrt(2)];
                h_annotate = plot(app.hConstellation.Parent,quad_xcoord,quad_ycoord ,'X'); % #ok
                set(h_annotate,'MarkerSize',10,'Color',[1 0 0],'LineWidth',1);

                app.doDisplayInit = false;
            end

        end

        function updateSignalAngle(app,value)
            setSignalAngle(app.hDemo,value);
            set(app.hSignalAngleLine,'Value',value,'Color','g',...
                'InterceptAxis','x',...
                'Label','Signal',...
                'LabelVerticalAlignment','bottom');
            app.doMVDRUpdate = true;
        end

        function updateInterfererAngle(app,value)
            setInterfererAngle(app.hDemo,value);
            set(app.hInterfererAngleLine,'Value',value,'Color','r',...
                'InterceptAxis','x',...
                'Label','Interference',...
                'LabelVerticalAlignment','bottom');
            app.doMVDRUpdate = true;
        end

        function updateSteeringAngle(app,value)
            setSteeringAngle(app.hDemo,value);
            resp = app.hDemo.getArrayResponse('phaseshift');
            set(app.hPhaseShiftResponseLine,'YData',resp,'Color','#0072BD','LineStyle','--' )
            set(app.hDesiredSteeringAngleLine,'Value',value,'Color','b',...
                'InterceptAxis','x',...
                'Label','Steering Angle');
            app.doMVDRUpdate = true;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize beam pattern plot
            initPlot(app);

            % Create timer to update displays
            app.displayUpdateTimer = timer('ExecutionMode','fixedRate',...
                'Period',0.2,'TimerFcn',@(~,~) app.updateDisplays());

            % Init demo object
            app.hDemo = RFSoCMVDRDemo(DebugMode=false);

            % Set values from UI
            setDiagonalLoading(app.hDemo,app.DiagonalLoadingEdit.Value);
            updateSignalAngle(app,app.SignalAngleSpinner.Value);
            setSignalGain(app.hDemo,app.SignalGainSpinner.Value);
            updateInterfererAngle(app,app.InterfererAngleSpinner.Value);
            setInterfererGain(app.hDemo,app.InterfererGainSpinner.Value);
            updateSteeringAngle(app,app.SteeringAngleSpinner.Value);

            % Start update display timer
            start(app.displayUpdateTimer);
        end

        % Value changed function: SignalAngleSlider
        function SignalAngleSliderValueChanged(app, event)
            value = app.SignalAngleSlider.Value;
            app.SignalAngleSpinner.Value = value;
            if (app.SteerLock.Value == 1)
                app.SteeringAngleSpinner.Value = value;
                app.SteeringAngleSlider.Value = value;
                updateSteeringAngle(app,value);
            end
            updateSignalAngle(app,value);
        end

        % Value changed function: SignalAngleSpinner
        function SignalAngleSpinnerValueChanged(app, event)
            value = app.SignalAngleSpinner.Value;
            app.SignalAngleSlider.Value = value;
            if (app.SteerLock.Value == 1)
                app.SteeringAngleSpinner.Value = value;
                app.SteeringAngleSlider.Value = value;
                updateSteeringAngle(app,value);
            end
            updateSignalAngle(app,value);
        end

        % Value changed function: SignalGainSpinner
        function SignalGainSpinnerValueChanged(app, event)
            value = app.SignalGainSpinner.Value;
            setSignalGain(app.hDemo,value);
        end

        % Value changed function: InterfererGainSpinner
        function InterfererGainSpinnerValueChanged(app, event)
            value = app.InterfererGainSpinner.Value;
            setInterfererGain(app.hDemo,value);
        end

        % Value changed function: InterfererAngleSpinner
        function InterfererAngleSpinnerValueChanged(app, event)
            value = app.InterfererAngleSpinner.Value;
            app.InterfererAngleSlider.Value = value;
            updateInterfererAngle(app,value);
        end

        % Value changed function: InterfererAngleSlider
        function InterfererAngleSliderValueChanged(app, event)
            value = app.InterfererAngleSlider.Value;
            app.InterfererAngleSpinner.Value = value;
            updateInterfererAngle(app,value);
        end

        % Close request function: RFSoCMVDRDemoUIFigure
        function RFSoCMVDRDemoUIFigureCloseRequest(app, event)
            if ~isempty(app.displayUpdateTimer)
                stop(app.displayUpdateTimer); % app-designer timer
            end
            delete(app);
        end

        % Value changed function: SteeringAngleSpinner
        function SteeringAngleSpinnerValueChanged(app, event)
            value = app.SteeringAngleSpinner.Value;
            app.SteeringAngleSlider.Value = value;
            if (app.SteerLock.Value == 1)
                app.SignalAngleSpinner.Value = value;
                app.SignalAngleSlider.Value = value;
                updateSignalAngle(app,value);
            end
            updateSteeringAngle(app,value);
        end

        % Value changed function: SteeringAngleSlider
        function SteeringAngleSliderValueChanged(app, event)
            value = app.SteeringAngleSlider.Value;
            app.SteeringAngleSpinner.Value = value;
            if (app.SteerLock.Value == 1)
                app.SignalAngleSpinner.Value = value;
                app.SignalAngleSlider.Value = value;
                updateSignalAngle(app,value);
            end
            updateSteeringAngle(app,value);
        end

        % Callback function
        function ConstellationSwitchValueChanged(app, event)
            value = app.ConstellationSwitch.Value;
            switch lower(value)
                case 'on'
                    app.hDemo.enableConstellation = true;
                case 'off'
                    app.hDemo.enableConstellation = false;
            end
        end

        % Callback function
        function SpectrumSwitchValueChanged(app, event)
            value = app.SpectrumSwitch.Value;
            switch lower(value)
                case 'on'
                    app.hDemo.enableSpectrum = true;
                case 'off'
                    app.hDemo.enableSpectrum = false;
            end
        end

        % Callback function
        function InterfererAngleSliderValueChanging(app, event)
            changingValue = event.Value;
            updateInterfererAngle(app,changingValue);
        end

        % Callback function
        function SteeringAngleSliderValueChanging(app, event)
            changingValue = event.Value;
            updateSteeringAngle(app,changingValue);
        end

        % Callback function
        function SignalAngleSliderValueChanging(app, event)
            changingValue = event.Value;
            updateSignalAngle(app,changingValue);
        end

        % Value changed function: SteerLock
        function SteerLockValueChanged(app, event)
            locked = app.SteerLock.Value;
            if locked == 1
                value = app.SignalAngleSlider.Value;
                app.SteeringAngleSlider.Value = value;
                app.SteeringAngleSpinner.Value = value;
                updateSteeringAngle(app,value);
            end
        end

        % Value changed function: DiagonalLoadingEdit
        function DiagonalLoadingEditValueChanged(app, event)
            value = app.DiagonalLoadingEdit.Value;
            if value < 0
                value = 0;
                app.DiagonalLoadingEdit.Value = value;
            end
            setDiagonalLoading(app.hDemo, value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RFSoCMVDRDemoUIFigure = uifigure('Visible', 'off');
            app.RFSoCMVDRDemoUIFigure.Name = 'RFSoC MVDR Demo';
            app.RFSoCMVDRDemoUIFigure.CloseRequestFcn = createCallbackFcn(app, @RFSoCMVDRDemoUIFigureCloseRequest, true);
            app.ResponsePlot = uiaxes(app.RFSoCMVDRDemoUIFigure);
            title(app.ResponsePlot, 'Beamforming Response')
            xlabel(app.ResponsePlot, 'Azimuth Angle (degrees)')
            ylabel(app.ResponsePlot, 'Normalized Power (dB)')
            zlabel(app.ResponsePlot, 'Z')
            app.ResponsePlot.XLim = [-90 90];
            app.ResponsePlot.YLim = [-50 10];
            app.ResponsePlot.XTick = [-90 -75 -60 -45 -30 -15 0 15 30 45 60 75 90];
            app.ResponsePlot.YTick = [-40 -30 -20 -10 0 10];
            app.SpectrumPlot = uiaxes(app.RFSoCMVDRDemoUIFigure);
            title(app.SpectrumPlot, 'Beamformed Signal Spectrum')
            xlabel(app.SpectrumPlot, 'Frequency (MHz)')
            ylabel(app.SpectrumPlot, 'dB')
            app.SpectrumPlot.XTick = [];
            app.SpectrumPlot.YTick = [];
            app.ConstellationPlot = uiaxes(app.RFSoCMVDRDemoUIFigure);
            title(app.ConstellationPlot, 'Constellation')
            xlabel(app.ConstellationPlot, 'In-Phase Amplitude')
            ylabel(app.ConstellationPlot, 'Quadrature Amplitude')
            zlabel(app.ConstellationPlot, 'Z')
            app.ConstellationPlot.XTick = [-1.5 -1 -0.5 0 0.5 1 1.5];
            app.ConstellationPlot.XTickLabel = {'-1.5'; '-1'; '-0.5'; '0'; '0.5'; '1'; '1.5'};
            app.ConstellationPlot.YTick = [-1.5 -1 -0.5 0 0.5 1 1.5];
            app.ConstellationPlot.YTickLabel = {'-1.5'; '-1'; '-0.5'; '0'; '0.5'; '1'; '1.5'};
            app.SteeringAngleLabel = uilabel(app.RFSoCMVDRDemoUIFigure);
            app.SteeringAngleLabel.Text = 'Steering Angle:';
            app.SteeringAngleSpinner = uispinner(app.RFSoCMVDRDemoUIFigure);
            app.SteeringAngleSpinner.Limits = [-90 90];
            app.SteeringAngleSpinner.RoundFractionalValues = 'on';
            app.SteeringAngleSpinner.ValueChangedFcn = createCallbackFcn(app, @SteeringAngleSpinnerValueChanged, true);
            app.SteeringAngleSpinner.Value = -45;
            app.degreesSpinnerLabel_3 = uilabel(app.RFSoCMVDRDemoUIFigure);
            app.degreesSpinnerLabel_3.Text = 'degrees';
            app.SteeringAngleSlider = uislider(app.RFSoCMVDRDemoUIFigure);
            app.SteeringAngleSlider.Limits = [-90 90];
            app.SteeringAngleSlider.MajorTicks = [-90 -75 -60 -45 -30 -15 0 15 30 45 60 75 90];
            app.SteeringAngleSlider.ValueChangedFcn = createCallbackFcn(app, @SteeringAngleSliderValueChanged, true);
            app.SteeringAngleSlider.Value = -45;
            app.InterferingSignalPanel = uipanel(app.RFSoCMVDRDemoUIFigure);
            app.InterferingSignalPanel.Title = 'Interfering Signal';
            app.InterfererAngleSlider = uislider(app.InterferingSignalPanel);
            app.InterfererAngleSlider.Limits = [-90 90];
            app.InterfererAngleSlider.MajorTicks = [-90 -75 -60 -45 -30 -15 0 15 30 45 60 75 90];
            app.InterfererAngleSlider.ValueChangedFcn = createCallbackFcn(app, @InterfererAngleSliderValueChanged, true);
            app.InterfererAngleSlider.Value = 17;
            app.dBLabel_2 = uilabel(app.InterferingSignalPanel);
            app.dBLabel_2.Text = 'dB';
            app.InterfererGainSpinner = uispinner(app.InterferingSignalPanel);
            app.InterfererGainSpinner.Step = 3;
            app.InterfererGainSpinner.Limits = [-30 3];
            app.InterfererGainSpinner.RoundFractionalValues = 'on';
            app.InterfererGainSpinner.ValueChangedFcn = createCallbackFcn(app, @InterfererGainSpinnerValueChanged, true);
            app.degreesSpinnerLabel_2 = uilabel(app.InterferingSignalPanel);
            app.degreesSpinnerLabel_2.Text = {'degrees'; ''};
            app.InterfererAngleSpinner = uispinner(app.InterferingSignalPanel);
            app.InterfererAngleSpinner.Limits = [-90 90];
            app.InterfererAngleSpinner.RoundFractionalValues = 'on';
            app.InterfererAngleSpinner.ValueChangedFcn = createCallbackFcn(app, @InterfererAngleSpinnerValueChanged, true);
            app.InterfererAngleSpinner.Value = 17;
            app.InterfererAngleLabel = uilabel(app.InterferingSignalPanel);
            app.InterfererAngleLabel.Text = 'Angle:';
            app.InterfererGainLabel = uilabel(app.InterferingSignalPanel);
            app.InterfererGainLabel.Text = 'Gain:';
            app.SignalofInterestPanel = uipanel(app.RFSoCMVDRDemoUIFigure);
            app.SignalofInterestPanel.Title = 'Signal of Interest';
            app.SignalAngleSlider = uislider(app.SignalofInterestPanel);
            app.SignalAngleSlider.Limits = [-90 90];
            app.SignalAngleSlider.MajorTicks = [-90 -75 -60 -45 -30 -15 0 15 30 45 60 75 90];
            app.SignalAngleSlider.ValueChangedFcn = createCallbackFcn(app, @SignalAngleSliderValueChanged, true);
            app.SignalAngleSlider.Value = -45;
            app.dBLabel = uilabel(app.SignalofInterestPanel);
            app.dBLabel.Text = 'dB';
            app.SignalGainSpinner = uispinner(app.SignalofInterestPanel);
            app.SignalGainSpinner.Step = 3;
            app.SignalGainSpinner.Limits = [-30 3];
            app.SignalGainSpinner.RoundFractionalValues = 'on';
            app.SignalGainSpinner.ValueChangedFcn = createCallbackFcn(app, @SignalGainSpinnerValueChanged, true);
            app.SignalGainSpinner.Value = -6;
            app.degreesSpinnerLabel = uilabel(app.SignalofInterestPanel);
            app.degreesSpinnerLabel.Text = {'degrees'; ''};
            app.SignalAngleSpinner = uispinner(app.SignalofInterestPanel);
            app.SignalAngleSpinner.Limits = [-90 90];
            app.SignalAngleSpinner.RoundFractionalValues = 'on';
            app.SignalAngleSpinner.ValueChangedFcn = createCallbackFcn(app, @SignalAngleSpinnerValueChanged, true);
            app.SignalAngleSpinner.Value = -45;
            app.SignalAngleLabel = uilabel(app.SignalofInterestPanel);
            app.SignalAngleLabel.Text = 'Angle:';
            app.SignalGainLabel = uilabel(app.SignalofInterestPanel);
            app.SignalGainLabel.Text = 'Gain:';
            app.SteerLock = uicheckbox(app.RFSoCMVDRDemoUIFigure);
            app.SteerLock.ValueChangedFcn = createCallbackFcn(app, @SteerLockValueChanged, true);
            app.SteerLock.Text = 'Lock to signal of interest';
            app.SteerLock.Value = true;
            app.MatrixsolveregularizationEditFieldLabel = uilabel(app.RFSoCMVDRDemoUIFigure);
            app.MatrixsolveregularizationEditFieldLabel.Text = 'Matrix solve regularization:';
            app.DiagonalLoadingEdit = uieditfield(app.RFSoCMVDRDemoUIFigure, 'numeric');
            app.DiagonalLoadingEdit.ValueDisplayFormat = '%.3e';
            app.DiagonalLoadingEdit.ValueChangedFcn = createCallbackFcn(app, @DiagonalLoadingEditValueChanged, true);
            app.DiagonalLoadingEdit.Tooltip = {'Set the diagonal loading value for the MVDR matrix solve.'; ''; 'Diagonal loading provides beamformer robustness due to small sample size and steering vector errors.'};
            app.DiagonalLoadingEdit.Value = 0.005;
            % Show the figure after all components are created
            app.RFSoCMVDRDemoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RFSoC_MVDR_Demo

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.RFSoCMVDRDemoUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.RFSoCMVDRDemoUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RFSoCMVDRDemoUIFigure)
        end
    end
end